classdef CommunicationLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ConnectButton     matlab.ui.control.Button
        DisconnectButton  matlab.ui.control.Button
        LEDONButton       matlab.ui.control.Button
        LEDOFFButton      matlab.ui.control.Button
        ADCUPDATEButton   matlab.ui.control.Button
        PotReadingLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        s
    end
    
    methods (Access = private)
        function connect(app)
            open_ports = instrfind('Type','serial','Status','open');
            if ~isempty(open_ports)
                 fclose(open_ports);
            end
            app.s = serial("/dev/cu.usbserial-DN0421L4", ...
                "BaudRate", 9600, ...
                "Terminator", 10, ...
                "BytesAvailableFcnMode", "terminator", ...
                "BytesAvailableFcn", @app.bytesRecieved);
            fopen(app.s);
            fprintf("\nConnected!\n");
        end
        function disconnect(app)
            fclose(app.s);
            fprintf("\nDisconnected!\n");
        end
        function bytesRecieved(app, serialObj, eventData)
            message = fscanf(serialObj);
            message = strtrim(message);
            fprintf("Received: %s\n", message);
            % Complete message match area...
            if strcmpi(message, "PRESSED RED")
                % TODO: Increment a red counter!
            end
            
            % Partial match area
            messageArray = split(message, " ");
            if strcmpi(messageArray(1), "POT")
                app.PotReadingLabel.Text = messageArray(2);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.connect()
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.connect()
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.disconnect()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.disconnect()
            delete(app)
        end

        % Button pushed function: LEDONButton
        function LEDONButtonPushed(app, event)
            fprintf(app.s, "ON");
        end

        % Button pushed function: LEDOFFButton
        function LEDOFFButtonPushed(app, event)
            fprintf(app.s, "OFF");
        end

        % Button pushed function: ADCUPDATEButton
        function ADCUPDATEButtonPushed(app, event)
            fprintf(app.s, "ADC");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.LEDONButton = uibutton(app.UIFigure, 'push');
            app.LEDONButton.ButtonPushedFcn = createCallbackFcn(app, @LEDONButtonPushed, true);
            app.LEDONButton.Text = 'LED ON';
            app.LEDOFFButton = uibutton(app.UIFigure, 'push');
            app.LEDOFFButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOFFButtonPushed, true);
            app.LEDOFFButton.Text = 'LED OFF';
            app.ADCUPDATEButton = uibutton(app.UIFigure, 'push');
            app.ADCUPDATEButton.ButtonPushedFcn = createCallbackFcn(app, @ADCUPDATEButtonPushed, true);
            app.ADCUPDATEButton.Text = 'ADC UPDATE';
            app.PotReadingLabel = uilabel(app.UIFigure);
            app.PotReadingLabel.Text = '1023';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CommunicationLab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end